classdef correction_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Browse2Button      matlab.ui.control.Button
        SourceFolder_2     matlab.ui.control.EditField
        Label_12           matlab.ui.control.Label
        DOFeCheckBox       matlab.ui.control.CheckBox
        magnification      matlab.ui.control.DropDown
        Label_11           matlab.ui.control.Label
        theta_end          matlab.ui.control.NumericEditField
        Label_10           matlab.ui.control.Label
        ParField           matlab.ui.control.NumericEditField
        Label_7            matlab.ui.control.Label
        GPUDropDown        matlab.ui.control.DropDown
        GPUDropDownLabel   matlab.ui.control.Label
        VolumeNum          matlab.ui.control.NumericEditField
        Label_6            matlab.ui.control.Label
        FinishLamp         matlab.ui.control.Lamp
        FinishLampLabel    matlab.ui.control.Label
        UserGuideTextArea  matlab.ui.control.TextArea
        Label_5            matlab.ui.control.Label
        BrowseButton       matlab.ui.control.Button
        Std                matlab.ui.control.NumericEditField
        Label_4            matlab.ui.control.Label
        Mean               matlab.ui.control.NumericEditField
        Label_3            matlab.ui.control.Label
        SourceFolder       matlab.ui.control.EditField
        Label_2            matlab.ui.control.Label
        RunButton          matlab.ui.control.Button
        MipNumber          matlab.ui.control.NumericEditField
        Label              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             clc
%             num = gpuDeviceCount;
%             app.GPUDropDown.Items = {'不使用'};
%             app.GPUDropDown.ItemsData = {'0'};
%             for i = 1:num
%                 app.GPUDropDown.Items = [app.GPUDropDown.Items, num2str(i)];
%                 app.GPUDropDown.ItemsData = [app.GPUDropDown.ItemsData, num2str(i)];
%             end
%             app.GPUDropDown.Value = '0';
        end

        % Value changed function: MipNumber
        function PreviewValueChanged(app, event)
            value = app.Preview.Value;
            app.FinishLamp.Color = [0.50,0.50,0.50];
            if value
                app.Mean.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.Std.Visible = 'off';
                app.Label_4.Visible = 'off';
            else
                app.Mean.Visible = 'on';
                app.Label_3.Visible = 'on';
                app.Std.Visible = 'on';
                app.Label_4.Visible = 'on';
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.SourceFolder.Value = uigetdir();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isfolder(app.SourceFolder.Value)
                clc
                app.FinishLamp.Color = [0.50,0.50,0.50];
%                 if app.ParField.Value>1
%                     parpool(app.ParField.Value);
%                 end
                main_config(app.SourceFolder.Value, ...
                            app.MipNumber.Value, app.Mean.Value, ...
                            app.Std.Value,...
                            app.VolumeNum.Value, ...
                            str2double(app.GPUDropDown.Value), ...
                            str2double(app.magnification.Value), ...
                            app.DOFeCheckBox.Value, ...
                            app.SourceFolder_2.Value);
%                 delete(gcp('nocreate'))
                app.FinishLamp.Color = [0.00,1.00,0.00];
            else
                warndlg('Please choose sorurse floder','warning');
            end
        end

        % Value changed function: Mean
        function MeanValueChanged(app, event)
            value = app.Mean.Value;
        end

        % Value changed function: SourceFolder_2
        function SourceFolder_2ValueChanged(app, event)
            value = app.SourceFolder_2.Value;
            
        end

        % Button pushed function: Browse2Button
        function Browse2ButtonPushed(app, event)
            app.SourceFolder_2.Value = uigetdir();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'number of calibration volumes';
            app.MipNumber = uieditfield(app.UIFigure, 'numeric');
            app.MipNumber.Limits = [0 Inf];
            app.MipNumber.RoundFractionalValues = 'on';
            app.MipNumber.ValueChangedFcn = createCallbackFcn(app, @PreviewValueChanged, true);
            app.MipNumber.Value = Inf;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'source path';
            app.SourceFolder = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'background mean';
            app.Mean = uieditfield(app.UIFigure, 'numeric');
            app.Mean.Limits = [0 Inf];
            app.Mean.ValueChangedFcn = createCallbackFcn(app, @MeanValueChanged, true);
            app.Mean.Editable = 'off';
            app.Mean.Value = 100;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'standard deviation';
            app.Std = uieditfield(app.UIFigure, 'numeric');
            app.Std.Limits = [0 Inf];
            app.Std.Editable = 'off';
            app.Std.Value = 3;
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'User Guide';
            app.UserGuideTextArea = uitextarea(app.UIFigure);
            app.UserGuideTextArea.Editable = 'off';
            app.UserGuideTextArea.Value = {'In the source path, please name the subfolders in the format of ''v_split_slice_step_...'', where ''v_'' is the identifier; ''split'' refers to the number of fields of view scanned in a dataset; ''slice'' represents the number of images taken per field of view; and ''step'' indicates the scanning step size. An optional annotation can be added after the final underscore.'; ''; 'Under the ''v_...'' folder, place the raw data in subfolders starting with ''H_...''. '};
            app.FinishLampLabel = uilabel(app.UIFigure);
            app.FinishLampLabel.Text = 'Finish';
            app.FinishLamp = uilamp(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'number of volume samples';
            app.VolumeNum = uieditfield(app.UIFigure, 'numeric');
            app.VolumeNum.Limits = [1 Inf];
            app.VolumeNum.Value = 1;
            app.GPUDropDownLabel = uilabel(app.UIFigure);
            app.GPUDropDownLabel.Enable = 'off';
            app.GPUDropDownLabel.Visible = 'off';
            app.GPUDropDownLabel.Text = 'GPU';
            app.GPUDropDown = uidropdown(app.UIFigure);
            app.GPUDropDown.Items = {'do not use', 'use'};
            app.GPUDropDown.ItemsData = {'0'};
            app.GPUDropDown.Enable = 'off';
            app.GPUDropDown.Visible = 'off';
            app.GPUDropDown.Value = '0';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Enable = 'off';
            app.Label_7.Visible = 'off';
            app.Label_7.Text = 'number of threads';
            app.ParField = uieditfield(app.UIFigure, 'numeric');
            app.ParField.Editable = 'off';
            app.ParField.Enable = 'off';
            app.ParField.Visible = 'off';
            app.ParField.Value = 1;
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Enable = 'off';
            app.Label_10.Visible = 'off';
            app.Label_10.Text = 'Finish';
            app.theta_end = uieditfield(app.UIFigure, 'numeric');
            app.theta_end.Limits = [1 Inf];
            app.theta_end.Editable = 'off';
            app.theta_end.Enable = 'off';
            app.theta_end.Visible = 'off';
            app.theta_end.Value = 55;
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'magnification';
            app.magnification = uidropdown(app.UIFigure);
            app.magnification.Items = {'60', '100', '40', '30', '20', '10'};
            app.magnification.Value = '60';
            app.DOFeCheckBox = uicheckbox(app.UIFigure);
            app.DOFeCheckBox.Text = 'DOFe';
            app.DOFeCheckBox.Value = true;
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = 'DL environment';
            app.SourceFolder_2 = uieditfield(app.UIFigure, 'text');
            app.SourceFolder_2.ValueChangedFcn = createCallbackFcn(app, @SourceFolder_2ValueChanged, true);
            app.Browse2Button = uibutton(app.UIFigure, 'push');
            app.Browse2Button.ButtonPushedFcn = createCallbackFcn(app, @Browse2ButtonPushed, true);
            app.Browse2Button.Text = 'Browse2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = correction_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end