classdef reconstruction_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BrowseButton                matlab.ui.control.Button
        FolderEditFieldLabel        matlab.ui.control.Label
        FolderEditField             matlab.ui.control.EditField
        NoiseMeanEditFieldLabel     matlab.ui.control.Label
        NoiseMeanEditField          matlab.ui.control.NumericEditField
        StdEditFieldLabel           matlab.ui.control.Label
        StdEditField                matlab.ui.control.NumericEditField
        MagnificationDropDownLabel  matlab.ui.control.Label
        M                           matlab.ui.control.DropDown
        RunButton                   matlab.ui.control.Button
        FinishLampLabel             matlab.ui.control.Label
        FinishLamp                  matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            main_path = fullfile(pwd, 'main');
            addpath(genpath(main_path))
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            temp = uigetdir();
            if temp ~= 0
                app.FolderEditField.Value = temp;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.FinishLamp.Color = [0.50,0.50,0.50];
            
            if ~isfolder(app.FolderEditField.Value)
                warndlg('Please choose sorurse floder','warning');
                return
            end
                
            clc
            
            config = struct();
            config.root = app.FolderEditField.Value;
            config.mean = app.NoiseMeanEditField.Value;
            config.std = app.StdEditField.Value;
            m = str2double(app.M.Value);
            config.pixelsize = 6.5/m;
            switch m
                case 100
                    temp = 44;
                case 60
                    temp = 54;
                case 40
                    temp = 45;
                case 30
                    temp = 65;
                case 20
                    temp = 66;
                case 10
                    temp = 78;
                otherwise
                    temp = 81;
            end
            
            config.theta = temp/180*pi;
            config.elastix_path = fullfile(pwd,'main','elastix');
            
            configure(config);
            
            app.FinishLamp.Color = [0.00,1.00,0.00];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.NoiseMeanEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseMeanEditFieldLabel.Text = 'Noise:Mean';
            app.NoiseMeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseMeanEditField.Value = 100;
            app.StdEditFieldLabel = uilabel(app.UIFigure);
            app.StdEditFieldLabel.Text = 'Std';
            app.StdEditField = uieditfield(app.UIFigure, 'numeric');
            app.StdEditField.Value = 3;
            app.MagnificationDropDownLabel = uilabel(app.UIFigure);
            app.MagnificationDropDownLabel.Text = 'Magnification';
            app.M = uidropdown(app.UIFigure);
            app.M.Items = {'100', '60', '40', '30', '20', '10'};
            app.M.Value = '60';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FinishLampLabel = uilabel(app.UIFigure);
            app.FinishLampLabel.Text = 'Finish';
            app.FinishLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reconstruction_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end